classdef TremorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TremorAppUIFigure             matlab.ui.Figure
        FileNameEditField             matlab.ui.control.EditField
        LoadFileButton                matlab.ui.control.Button
        AnalysisPanel                 matlab.ui.container.Panel
        SamplingHzEditFieldLabel      matlab.ui.control.Label
        SamplingHzEditField           matlab.ui.control.NumericEditField
        ProtocolOrderTextAreaLabel    matlab.ui.control.Label
        ProtocolOrderTextArea         matlab.ui.control.TextArea
        RunButton                     matlab.ui.control.Button
        EpochSizesEditFieldLabel      matlab.ui.control.Label
        EpochSizesEditField           matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        NameLabel                     matlab.ui.control.Label
        OpLabel                       matlab.ui.control.Label
        DateLabel                     matlab.ui.control.Label
        SummaryButton                 matlab.ui.control.Button
        SideLabel                     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        FilteringTab                  matlab.ui.container.Tab
        LowerLimitHzLabel             matlab.ui.control.Label
        LowerLimitHzEditField         matlab.ui.control.NumericEditField
        UpperLimitHzEditFieldLabel    matlab.ui.control.Label
        UpperLimitHzEditField         matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel     matlab.ui.control.Label
        FilterOrderEditField          matlab.ui.control.NumericEditField
        DisplayButtonGroup            matlab.ui.container.ButtonGroup
        ShowFilterDesignButton        matlab.ui.control.Button
        ShowFilteringButton           matlab.ui.control.Button
        PCAnalysisTab                 matlab.ui.container.Tab
        PCAButtonGroup                matlab.ui.container.ButtonGroup
        PostfilteringButton           matlab.ui.control.RadioButton
        SelectedProtocolPeriodButton  matlab.ui.control.RadioButton
        DisplayButtonGroup_2          matlab.ui.container.ButtonGroup
        DisplayPCACloudButton         matlab.ui.control.Button
        EpochsTab                     matlab.ui.container.Tab
        EpochSelectionButtonGroup     matlab.ui.container.ButtonGroup
        AutoButton                    matlab.ui.control.ToggleButton
        ManualButton                  matlab.ui.control.ToggleButton
        DisplayButtonGroup_3          matlab.ui.container.ButtonGroup
        ShowSelectedEpochsButton      matlab.ui.control.Button
        PC1CheckBox                   matlab.ui.control.CheckBox
        PC2CheckBox                   matlab.ui.control.CheckBox
        PC3CheckBox                   matlab.ui.control.CheckBox
        ProtocolTypeButtonGroup       matlab.ui.container.ButtonGroup
        SinglePeakStartButton         matlab.ui.control.RadioButton
        DoublePeakStartButton         matlab.ui.control.RadioButton
        MovementTab                   matlab.ui.container.Tab
        DisplayButtonGroup_5          matlab.ui.container.ButtonGroup
        ShowMovementButton            matlab.ui.control.Button
        FilterMovementCheckBox        matlab.ui.control.CheckBox
        PowerTab                      matlab.ui.container.Tab
        PCButtonGroup                 matlab.ui.container.ButtonGroup
        Button                        matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        TracesButtonGroup             matlab.ui.container.ButtonGroup
        AverageOnlyButton             matlab.ui.control.ToggleButton
        AllButton                     matlab.ui.control.ToggleButton
        DisplayButtonGroup_4          matlab.ui.container.ButtonGroup
        ShowPowerButton               matlab.ui.control.Button
        PowerRangeEditFieldLabel      matlab.ui.control.Label
        PowerRangeEditField           matlab.ui.control.NumericEditField
        SaveDataButton                matlab.ui.control.Button
    end


    properties (Access = public)
        RawData=struct();
        PeriodData=struct();
        TrialData=struct();
        AllPCdata=struct();
        PCoutput=struct();
        FilterObject=[];
    end


    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Ask user to load the file name into UI
            [file,path] = uigetfile('*');
            
            app.FileNameEditField.Value=[path,file];
            try
                app.TextArea.Value=[app.TextArea.Value;file(1:20),'... has been loaded.'];
            catch
                app.TextArea.Value=[app.TextArea.Value;file(1:end),'... has been loaded.'];
            end
            
            sections=strsplit(file,'_');
            Name=sections{1};
            Side=sections{2};
            sections=strsplit(sections{end},' ');
            date=sections{1};
            
            try 
                sections=strsplit(path,'\');
                string=sections{end-1};
                switch string
                    case {'PreOp','Pre-Op','preop','pre-op'}
                        opStatus='PreOp';
                    case {'PostOp','Post-Op','postop','post-op'}
                        opStatus='PostOp';
                end
                if isempty(opStatus)
                    error('Cannot determine Op Status from file path');
                end
            catch
            
            opStatus=questdlg ('When was this data taken?', 'Op Status','PreOp','PostOp','PreOp');
            end
            
            app.OpLabel.Text=opStatus;
            app.DateLabel.Text=date;
            app.NameLabel.Text=Name;
            app.SideLabel.Text=Side;
            
            app.TremorAppUIFigure.Visible = 'off';
            app.TremorAppUIFigure.Visible = 'on';
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            %% MAIN CODE, THIS PART ID WHAT POPULATES ALL DATA STRUCTURES
            
            %% Parameters
            % Get file address
            filepath = app.FileNameEditField.Value;
            if isempty(filepath)
                app.TextArea.Value='No file selected, please load file.';
                error('No file selected');
            end
            % Get sampling frequency
            Fs=app.SamplingHzEditField.Value; % in Hz
            % Set up protocol values
            ProtocolOrder=strsplit(app.ProtocolOrderTextArea.Value{1},',');
            if app.SinglePeakStartButton.Value==1
                ProtocolType=1;
            end
            if app.DoublePeakStartButton.Value==1
                ProtocolType=2;
            end
            
            % PC analysis parmeters
            if app.PostfilteringButton.Value==1
            PCAorder='Raw'; %
            end
            if app.SelectedProtocolPeriodButton.Value==1
                PCAorder='Segment';
            end
            
            % Epoch size
            TimeWindow=app.EpochSizesEditField.Value; % length of segment data to be taken from trial (seconds)
            
            % Sampling frequency
            SampFreq=app.SamplingHzEditField.Value;
            
            % Percent power for range analysis
            PercentPower=app.PowerRangeEditField.Value;
            
            % Filter parameters
            BandFreqRange=[app.LowerLimitHzEditField.Value,app.UpperLimitHzEditField.Value]; % in Hz
            FilterOrder=app.FilterOrderEditField.Value;
            
            %% Import Data
            [time, x, y, z, light] = OpenAccFile(filepath);
            
            %% Detrend acc, filter unwanted frequencies
            
            % Filter Design
            BandPassFilt=designfilt('bandpassfir','FilterOrder',FilterOrder, ...
                'CutoffFrequency1',BandFreqRange(1),'CutoffFrequency2',BandFreqRange(2), ...
                'SampleRate',Fs);
            app.FilterObject=BandPassFilt;
            % fvtool(BandPassFilt)
            
            % Filter Data
            xnd=filter(BandPassFilt,x);
            ynd=filter(BandPassFilt,y);
            znd=filter(BandPassFilt,z);
            
            % Correct for the phase shift
            N=length(x);
            % grpdelay(BandPassFilt,N,Fs)
            [gd,w]=grpdelay(BandPassFilt,N,Fs);
            delay=mean(gd);
            
            xnd(1:delay)=[];
            ynd(1:delay)=[];
            znd(1:delay)=[];
            x=x(1:end-delay);
            y=y(1:end-delay);
            z=z(1:end-delay);
            
            % This is to just remove linear component
            xnd = detrend(xnd,'linear');
            ynd = detrend(ynd,'linear');
            znd = detrend(znd,'linear');
            
            % save Data
            app.RawData.Time=time;
            app.RawData.Signals=[transpose(x);transpose(y);transpose(z)];
            app.RawData.light=light;
            app.RawData.FiltSignals=[transpose(xnd);transpose(ynd);transpose(znd)];
            
            %% Select Data to be Analyzed
            
            % Select Trial Period
            switch PCAorder
                case 'Raw'
                    
                    % Perform PCA
                    r = [xnd,ynd,znd];
                    [coeff,score,latent,tsquared,explained,mu] = pca(r);
                    PCsignals = score;
                    
                    % Display data cloud + component vectors
                    downsampleFactor=1;
                    LineWidth=2;
                    Mag=10;
                    %         CloudPCAdisplay(xnd,ynd,znd,coeff,downsampleFactor,Mag,explained,LineWidth);
                    
                    % Select protocol segment
                    [Start,End]=SelectSignalManual(PCsignals,light,explained,PCAorder,SampFreq);
                    PCsig_period=PCsignals(Start:End,:);
                    
                case 'Segment'
                    
                    % Select Segment
                    r = [xnd,ynd,znd];
                    [Start,End]=SelectSignalManual(r,light,[0,0,0],PCAorder,SampFreq);
                    RAWsig_period=r(Start:End,:);
                    % Perform PCA
                    [coeff,score,latent,tsquared,explained,mu] = pca(RAWsig_period);
                    PCsig_period=score;
                    % Display data cloud + component vectors
                    downsampleFactor=1;
                    LineWidth=2;
                    Mag=10;
                    xseg=RAWsig_period(:,1);
                    yseg=RAWsig_period(:,2);
                    zseg=RAWsig_period(:,3);
                    %         CloudPCAdisplay(xseg,yseg,zseg,coeff,downsampleFactor,Mag,explained,LineWidth);
            end
            app.PeriodData.Signals=transpose(PCsig_period);
            app.PeriodData.Light=transpose(light(Start:End));
            
            app.PCoutput.coeff=coeff;
            app.PCoutput.score=score;
            app.PCoutput.latent=latent;
            app.PCoutput.tsquared=tsquared;
            app.PCoutput.explained=explained;
            app.PCoutput.mu=mu;
            
            light_period=light(Start:End);
            periodCut=[Start,End];
            % Select Specific Trials
            close(gcf)
            if app.AutoButton.Value==1
                selectionMethod='Auto';
            else
                selectionMethod='Manual';
            end
            
            [TrialS]=PopEpochs(PCsig_period,light_period,ProtocolOrder,ProtocolType,explained,TimeWindow,Fs,selectionMethod);
            
            NumPCs=size(PCsig_period,2);
            PCsig_trial=struct('PC',[],'Var',[],'TrialSignals',[],'PowerSpectrum',[]);
            
            %% Perform Analysis
            % for this analysis we are averaging all trials with same conditions
            
            
            % FFT for power spectrum
            
            Fs=Fs; % sampling frequency
            T=1/Fs; % sampling period
            
            for trial=1:size(TrialS,2)
                
                powerspect=[];
                for pc=1:NumPCs
                    
                    trialSignal=TrialS(trial).CutSignals(pc,:);
                    
                    L=length(trialSignal); % length of signal in # of readings
                    timeVector=(0:L-1)*T; % time vector in seconds
                    f=Fs*(0:(L/2))/L;
                    
                    Y=fft(trialSignal); % this is the transform
                    P2 = abs(Y/L); % this is the two sided power spectrum
                    P1 = P2(1:L/2+1);
                    P1(2:end-1) = 2*P1(2:end-1); % this is the single sided power spectrum, assumes L is constant
                    
                    powerspect(pc,:)=P1;
                    
                    %         % plot result of FFT
                    %         figure
                    %         subplot(2,1,1)
                    %         plot((0:L-1)*T,trialSignal);
                    %         title(['PC',num2str(pc),' trial ',TrialS(trial).Trial]);
                    %         xlabel('time (s)')
                    %         ylabel('Acc mG')
                    %
                    %         subplot(2,1,2)
                    %         f=Fs*(0:(L/2))/L;
                    %         plot(f,P1);
                    %         xlabel('freq (Hz)')
                    %         ylabel('Abs Power')
                    
                end
                
                TrialS(trial).PowerSpectrum=powerspect;
                
            end
            
            %% Sort into PCs for each protocol order
            AllPCdataS=struct('PC',[],'DataS',[],'Var',[]);
            ProtocolOrder=[ProtocolOrder,'Rest'];
            
            for pc=1:NumPCs
                
                % Initialize PC specific structure
                PCdataS=struct('Condition',[],'Signals',[],'SignalAverage',[],'Power',[],'PowerAverage',[]);
                for i=1:length(ProtocolOrder)
                    PCdataS(i).Condition=ProtocolOrder{i};
                end
                
                % Sort trial data into PC,condtion specific adresses
                conditionCounts=ones(1,length(ProtocolOrder));
                
                for trial=1:size(TrialS,2)
                    
                    trialName=TrialS(trial).Trial;
                    trialSignal=TrialS(trial).CutSignals(pc,:);
                    trialPower=TrialS(trial).PowerSpectrum(pc,:);
                    
                    for condition=1:length(ProtocolOrder)
                        if strcmp(trialName,PCdataS(condition).Condition)
                            
                            PCdataS(condition).Signals(conditionCounts(condition),:)=trialSignal;
                            PCdataS(condition).Power(conditionCounts(condition),:)=trialPower;
                            conditionCounts(condition)=conditionCounts(condition)+1;
                            
                        end
                    end
                end
                
                % Compute and save potential display data
                for condition=1:length(ProtocolOrder)
                    
                    % Averages
                    PCdataS(condition).SignalAverage=mean(PCdataS(condition).Signals,1);
                    PCdataS(condition).PowerAverage=mean(PCdataS(condition).Power,1);
                    
                    % Median power frequency (frequency at 50% power)
                    PCdataS(condition).MedianFreq = medfreq(PCdataS(condition).PowerAverage,f);
                    
                    % Power peak frequency
                    PCdataS(condition).PeakFreq = findPeak(PCdataS(condition).PowerAverage,f);
                    
                    % Power dispersion at median freq (width of PercentPower% of power)
                    peakFreq=PCdataS(condition).PeakFreq;
                    avgPower=PCdataS(condition).PowerAverage;
                    PCdataS(condition).PeakFreqRange = FreqRange(peakFreq,avgPower,f,PercentPower);
                    
                    % Power dispersion at peak freq (PercentPower%% power)
                    medianFreq=PCdataS(condition).MedianFreq;
                    PCdataS(condition).PeakFreqRange = FreqRange(medianFreq,avgPower,f,PercentPower);
                    
                end
                
                % Save data
                AllPCdataS(pc).PC=pc;
                AllPCdataS(pc).DataS=PCdataS;
                AllPCdataS(pc).Var=explained(pc);
                
            end
            
            app.TrialData=TrialS;
            app.AllPCdata=AllPCdataS;
            app.TextArea.Value='Analysis successful';
            %% END OF MAIN CODE
            
            set(app.SummaryButton, 'Enable', 'on');
            
        end

        % Value changed function: EpochSizesEditField
        function EpochSizesEditFieldValueChanged(app, event)
            value = app.EpochSizesEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Epoch Size Changed: ',num2str(value),'s. '];
            set(app.SummaryButton, 'Enable', 'off');
        end

        % Value changed function: SamplingHzEditField
        function SamplingHzEditFieldValueChanged(app, event)
            value = app.SamplingHzEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Sampling Frequency Changed: ',num2str(value),'Hz.'];
            set(app.SummaryButton, 'Enable', 'off');
        end

        % Value changed function: ProtocolOrderTextArea
        function ProtocolOrderTextAreaValueChanged(app, event)
            value = app.ProtocolOrderTextArea.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Protocol Changed: ',value,'.'];
            set(app.SummaryButton, 'Enable', 'off');
        end

        % Value changed function: LowerLimitHzEditField
        function LowerLimitHzEditFieldValueChanged(app, event)
            value = app.LowerLimitHzEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Lower Filter Limit Changed: ',num2str(value),'.'];
            set(app.SummaryButton, 'Enable', 'off');
        end

        % Value changed function: UpperLimitHzEditField
        function UpperLimitHzEditFieldValueChanged(app, event)
            value = app.UpperLimitHzEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Upper Filter Limit Changed: ',num2str(value),'.'];
            set(app.SummaryButton, 'Enable', 'off');

        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Filter Order Changed: ',num2str(value),'.'];
            set(app.SummaryButton, 'Enable', 'off');
            
        end

        % Selection changed function: PCAButtonGroup
        function PCAButtonGroupSelectionChanged(app, event)
            selectedButton = app.PCAButtonGroup.SelectedObject;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... PCA order changed: ',selectedButton.Text,'.'];
            set(app.SummaryButton, 'Enable', 'off');

        end

        % Selection changed function: ProtocolTypeButtonGroup
        function ProtocolTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProtocolTypeButtonGroup.SelectedObject;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Protocol Type Changed: ',selectedButton.Text,'.'];
            set(app.SummaryButton, 'Enable', 'off');
            
        end

        % Value changed function: PowerRangeEditField
        function PowerRangeEditFieldValueChanged(app, event)
            value = app.PowerRangeEditField.Value;
            
            app.RawData=struct();
            app.PeriodData=struct();
            app.TrialData=struct();
            app.AllPCdata=struct();
            app.FilterObject=[];
            
            app.TextArea.Value=[app.TextArea.Value;'... Power Range Changed: ',num2str(value),'%.'];
            set(app.SummaryButton, 'Enable', 'off');
            
        end

        % Callback function
        function ShowFilteringButtonValueChanged(app, event)


        end

        % Button pushed function: ShowFilterDesignButton
        function ShowFilterDesignButtonPushed(app, event)
            
            % Filter parameters
            BandFreqRange=[app.LowerLimitHzEditField.Value,app.UpperLimitHzEditField.Value]; % in Hz
            FilterOrder=app.FilterOrderEditField.Value;
            Fs=app.SamplingHzEditField.Value;
            % Filter Design
            BandPassFilt=designfilt('bandpassfir','FilterOrder',FilterOrder, ...
                'CutoffFrequency1',BandFreqRange(1),'CutoffFrequency2',BandFreqRange(2), ...
                'SampleRate',Fs);
            fvtool(BandPassFilt)
        end

        % Button pushed function: ShowFilteringButton
        function ShowFilteringButtonPushed(app, event)
            
            
            x=app.RawData.Signals(1,:);
            y=app.RawData.Signals(2,:);
            z=app.RawData.Signals(3,:);
            xnd=app.RawData.FiltSignals(1,:);
            ynd=app.RawData.FiltSignals(2,:);
            znd=app.RawData.FiltSignals(3,:);
            
            Fs=app.SamplingHzEditField.Value;
            
            Y=(1:length(x))/Fs; % time in seconds
            
            figure
            
            subplot(2,1,1)
            plot(Y,x,Y,y,Y,z)
            ylabel('Acceleration mG');
            xlabel('Time s');
            title('Raw Acc Data');
            
            subplot(2,1,2)
            plot(Y,xnd,Y,ynd,Y,znd)
            ylabel('Acceleration mG');
            xlabel('Time s');
            title('Filtered Acc Data');


        end

        % Button pushed function: DisplayPCACloudButton
        function DisplayPCACloudButtonPushed(app, event)
            
            if app.PostfilteringButton.Value==1
            PCAorder='Raw'; %
            end
            if app.SelectedProtocolPeriodButton.Value==1;
                PCAorder='Segment';
            end
            switch PCAorder
                case 'Raw'
                    X=app.RawData.Signals(1,:);
                    Y=app.RawData.Signals(2,:);
                    Z=app.RawData.Signals(3,:);
                case 'Segment'
                    X=app.PeriodData.Signals(1,:);
                    Y=app.PeriodData.Signals(2,:);
                    Z=app.PeriodData.Signals(3,:);
            end
            % Display data cloud + component vectors
            downsampleFactor=1;
            LineWidth=2;
            Mag=10;
            coeff=app.PCoutput.coeff;
            explained=app.PCoutput.explained;
            CloudPCAdisplay(X,Y,Z,coeff,downsampleFactor,Mag,explained,LineWidth);
            
        end

        % Button pushed function: ShowSelectedEpochsButton
        function ShowSelectedEpochsButtonPushed(app, event)
            
            % Get data
            Fs=app.SamplingHzEditField.Value;
            signal=transpose(app.PeriodData.Signals);
            EpochS=app.TrialData;
            explained=app.PCoutput.explained;
            light=app.PeriodData.Light;
            X=(1:size(signal,1));
            
            PCprint=[1 1 1];
            if app.PC1CheckBox.Value ==0
                PCprint(1)=0;
            end
            if app.PC2CheckBox.Value ==0
                PCprint(2)=0;
            end
            if app.PC3CheckBox.Value ==0
                PCprint(3)=0;
            end
            
            % Display final graph with cut epochs
            figure
                %set(gcf, 'Units', 'Normalized', 'OuterPosition', [0, 0.04, 1, 0.96]);
            subplot(2,1,1);
            hold on
            legendStr=[];
            colors=lines(3);
            for i=1:3
                if PCprint(i)==1
                    plot(X,signal(:,i),'Color',colors(i,:));
                    legendStr=[legendStr,'split','PCA',num2str(i),' ',num2str(explained(i)),'%var'];
                end
            end
            legendStr=strsplit(legendStr,'split');
            legendStr=legendStr(2:end);
            
            hold on;
            for epochcount = 1:size(EpochS,2)
                
                if mod(epochcount,2) == 0
                    c = [0 0 1];
                    text(EpochS(epochcount).CutStart,(-1.5),'Rest');
                else
                    c = [1 0 0];
                    text(EpochS(epochcount).CutStart,(1.5),EpochS(epochcount).Trial);
                end
                
                p = patch([EpochS(epochcount).CutStart,EpochS(epochcount).CutEnd,EpochS(epochcount).CutEnd,EpochS(epochcount).CutStart],...
                    [-1,-1,1,1],c);
                alpha(p,.1);
                
            end
            legend(legendStr)
            hold off
            title('Final Processed Epochs')
            
            subplot(2,1,2);
            plot(light,'r');
            for i=1:epochcount
                line([EpochS(i).SelectStart,EpochS(i).SelectStart],[0 30000]);
                line([EpochS(i).SelectEnd,EpochS(i).SelectEnd],[0 30000]);
            end
            legend('Luminosity');
            xlabel('X (#samples)');


        end

        % Button pushed function: ShowMovementButton
        function ShowMovementButtonPushed(app, event)
            
            % Get data
            PCsig_period=transpose(app.PeriodData.Signals);
            Fs=app.SamplingHzEditField.Value; %Hz
            BandPassFilt=app.FilterObject;
            AllPCdataS=app.AllPCdata;
            TrialS=app.TrialData;
            figure
            %% Display whole period acceleration
            
            legendVector={};
            [maxA,maxV,maxD]=deal(0);
            hold on
            for pc=1:size(PCsig_period,2)
                
                acceleration=transpose(PCsig_period(:,pc));
                acceleration=acceleration/1000*9.8; % adjust to proper units (m/s^2)
                time=transpose(1:length(acceleration))*1/Fs; % adjust to units, (s)
                
                % cumtrapz method (fast but innacurate at very very small displacements)
                velocity=detrend(cumtrapz(time,acceleration))*1000;
                
                % filter velocity
                if app.FilterMovementCheckBox.Value==1
                    velocity=filter(BandPassFilt,velocity);
                    [gd,w]=grpdelay(BandPassFilt,length(velocity),Fs);
                    delay=mean(gd);
                    velocity(1:delay)=[];
                    time=time(1:end-delay);
                    acceleration=acceleration(1:end-delay);
                end
                
                displacement=detrend(cumtrapz(time,velocity));
                
                % filter displacement
                if app.FilterMovementCheckBox.Value==1
                    displacement=filter(BandPassFilt,displacement);
                    [gd,w]=grpdelay(BandPassFilt,length(displacement),Fs);
                    delay=mean(gd);
                    displacement(1:delay)=[];
                    time=time(1:end-delay);
                    acceleration=acceleration(1:end-delay);
                    velocity=velocity(1:end-delay);
                end
                
                legendVector{pc}=['PC',num2str(pc),' var ',num2str(AllPCdataS(pc).Var)];
                if max(abs(acceleration))>maxA
                    maxA=max(acceleration);
                end
                if max(abs(velocity))>maxV
                    maxV=max(velocity);
                end
                if max(abs(displacement))>maxD
                    maxD=max(displacement);
                end
                
                subplot(3,1,1)
                hold on
                plot(time,acceleration)
                subplot(3,1,2)
                hold on
                plot(time,velocity*1000)
                subplot(3,1,3)
                hold on
                plot(time,displacement*1000)
                
            end
            subplot(3,1,1)
            hold on
            for i=1:size(TrialS,2)
                x1=TrialS(i).CutStart/Fs;
                x2=TrialS(i).CutEnd/Fs;
                if mod(i,2)==0
                    p=patch([x1 x1 x2 x2],[-maxA maxA maxA -maxA],'b');
                    alpha(p,.1);
                else
                    p=patch([x1 x1 x2 x2],[-maxA maxA maxA -maxA],'r');
                    alpha(p,.1);
                end
            end
            title('Acceleration traces for Protocol Period');
            xlabel('Time (s)')
            ylabel('Acceleration (m/s^2)')
            legend(legendVector)
            
            subplot(3,1,2)
            hold on
            maxV=maxV*1000;
            for i=1:size(TrialS,2)
                x1=TrialS(i).CutStart/Fs;
                x2=TrialS(i).CutEnd/Fs;
                if mod(i,2)==0
                    p=patch([x1 x1 x2 x2],[-maxV maxV maxV -maxV],'b');
                    alpha(p,.1);
                else
                    p=patch([x1 x1 x2 x2],[-maxV maxV maxV -maxV],'r');
                    alpha(p,.1);
                end
            end
            title('Velocity traces for Protocol Period');
            xlabel('Time (s)')
            ylabel('Velocity (mm/s)')
            
            subplot(3,1,3)
            hold on
            maxD=maxD*1000;
            for i=1:size(TrialS,2)
                x1=TrialS(i).CutStart/Fs;
                x2=TrialS(i).CutEnd/Fs;
                if mod(i,2)==0
                    p=patch([x1 x1 x2 x2],[-maxD maxD maxD -maxD],'b');
                    alpha(p,.1);
                else
                    p=patch([x1 x1 x2 x2],[-maxD maxD maxD -maxD],'r');
                    alpha(p,.1);
                end
            end
            title('Displacement traces for Protocol Period');
            xlabel('Time (s)')
            ylabel('Displacement (mm)')
            hold off
            clear maxV maxA maxD
            
        end

        % Button pushed function: ShowPowerButton
        function ShowPowerButtonPushed(app, event)
            
            %% Display Tremor
            PC=[];
            if app.Button.Value==1
                PC=1;
            else 
                if app.Button_2.Value==1
                    PC=2;
                else 
                    if app.Button_3.Value==1
                        PC=3;
                    end
                end
            end
            
            axisLim=[0 25 0 0.5];
            lineWidth=2;
            PlotAll=0;
            if app.AllButton.Value==1
                PlotAll=1;
            end
            
            %% Load data
            Data=app.AllPCdata(PC).DataS;
            variance=app.AllPCdata(PC).Var;
            numConditions=size(Data,2);
            
            %% Graph
            % colors=distinguishable_colors(numConditions,'w');
            colors=colormap(lines);
            legendCount=1;
            figure
            hold on
            
            %plot averages
            for condition=1:numConditions
                L=length(Data(condition).SignalAverage); % length of signal in # of readings
                f=app.SamplingHzEditField.Value*(0:(L/2))/L;
                
                X=f;
                Y=Data(condition).PowerAverage;
                plot(X,Y, 'Color',colors(condition,:),'LineWidth',lineWidth);
                legendNames{condition}=Data(condition).Condition;
                
                Xtext=round(Data(condition).PeakFreq,2);
                Ytext=max(Y);
                text(Xtext,Ytext,['\downarrow ',num2str(Xtext),' Hz'],'Color',colors(condition,:));
            end
            %plot signals
            if PlotAll
                for condition=1:numConditions
                    Y=Data(condition).Power;
                    plot(X,Y,'Color',colors(condition,:),'LineStyle',':','LineWidth',lineWidth/2);
                    legendCount=legendCount+1;
                end
            end
            
            axis(axisLim)
            title(['PC',num2str(PC),' ',num2str(round(variance)),'%var. Single Sided Amplitude Spectrum']);
            xlabel('Frequency (Hz)')
            ylabel('Power')
            legend(legendNames)
            
            hold off
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            
            if isempty(app.AllPCdata)
                app.TextArea.Value='No data... cannot save';
                error('No data present');
            end
            
            % create data to be saved
            %             SaveStructure=struct('FilterObject',app.FilterObject,...
            %                                  'RawData',app.RawData,'PeriodData',app.PeriodData,...
            %                                  'TrialData',app.TrialData,'AllPCdata',app.AllPCdata,...
            %                                  'PCoutput',app.PCoutput);
            
            SaveStructure=struct('FilterObject',app.FilterObject,...
                'TrialData',app.TrialData,'AllPCdata',app.AllPCdata,...
                'PCoutput',app.PCoutput);
            
            datafile=app.FileNameEditField.Value;
            datafile=strsplit(datafile,'\');
            filename=datafile{end};
            filename=strsplit(filename,'.');
            filename=filename{1};
            filename=['Data_',filename];
            
            % save data
            uisave('SaveStructure',filename);
            app.TextArea.Value='Save complete';
            
        end

        % Button pushed function: SummaryButton
        function SummaryButtonPushed(app, event)
            %% GET DATA
            
            figure 
            
            PC=[];
            if app.Button.Value==1
                PC=1;
            else 
                if app.Button_2.Value==1
                    PC=2;
                else 
                    if app.Button_3.Value==1
                        PC=3;
                    end
                end
            end
            
            Data=app.AllPCdata(PC).DataS;
            variance=app.AllPCdata(PC).Var;
            numConditions=size(Data,2);
            
            %% Parameters
            axisLim=[0 25 0 1];
            lineWidth=2;
            colors=colormap(lines);
            
            %% FIGURE
            
            NumSubPlots= 2+numConditions;
            HorzSub=2;
            VertSub=ceil(NumSubPlots/HorzSub);
            
            % Main subplot
            subplot (VertSub,HorzSub,1:HorzSub)
            
            hold on
            %plot averages
            Xtext=24.5;
            Ytext=0.35;
            for condition=1:numConditions
                L=length(Data(condition).SignalAverage); % length of signal in # of readings
                f=app.SamplingHzEditField.Value*(0:(L/2))/L;
                X=f;
                
                Y=Data(condition).PowerAverage;
                plot(X,Y, 'Color',colors(condition,:),'LineWidth',lineWidth);
                legendNames{condition}=Data(condition).Condition;
                
                Xlabel=round(Data(condition).PeakFreq,2);
                text(Xtext,Ytext,['Peak ',num2str(Xlabel),' Hz'],'Color',colors(condition,:),...
                     'HorizontalAlignment','right');
                Ytext=Ytext-0.07;
            end
            
            axis(axisLim)
            title(['PC',num2str(PC),' ',num2str(round(variance)),'%var. Single Sided Amplitude Spectrum']);
            xlabel('Frequency (Hz)')
            ylabel('Power')
            legend(legendNames)
            
            hold off
            
            % Individual Subplots
            for condition=1:numConditions
                
                subNum=HorzSub+condition;
                subplot (VertSub,HorzSub,subNum)
                hold on
                
                L=length(Data(condition).SignalAverage); % length of signal in # of readings
                f=app.SamplingHzEditField.Value*(0:(L/2))/L;
                X=f;
                
                Y=Data(condition).Power;
                plot(X,Y,'Color',colors(condition,:),'LineStyle',':','LineWidth',lineWidth/2);
                
                Y=Data(condition).PowerAverage;
                plot(X,Y, 'Color',colors(condition,:),'LineWidth',lineWidth);
                
                Xtext=round(Data(condition).PeakFreq,2);
                Ytext=max(Y);
                text(Xtext,Ytext,['\downarrow ',num2str(Xtext),' Hz'],'Color',colors(condition,:));
                
                %text(24.5,0.2,['Peak Freq Range (',num2str(app.PowerRangeEditField.Value),'%): ',...
                %                num2str(Data(condition).PeakFreqRange(1)),' to ',...
                %                num2str(Data(condition).PeakFreqRange(2))],...
                %                'Color',colors(condition,:),'HorizontalAlignment','right');
                            
                axis(axisLim)
                title([Data(condition).Condition,' (',num2str(size(Data(condition).Power,1)),' epochs)']);
                xlabel('Frequency (Hz)')
                ylabel('Power')
                hold off
                            
            end
            
            suptitle([app.NameLabel.Text,'  ',app.OpLabel.Text,'  ',app.SideLabel.Text,'  ',app.DateLabel.Text]);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TremorAppUIFigure = uifigure;
            app.TremorAppUIFigure.Name = 'Tremor App';
            app.FileNameEditField = uieditfield(app.TremorAppUIFigure, 'text');
            app.LoadFileButton = uibutton(app.TremorAppUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.AnalysisPanel = uipanel(app.TremorAppUIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.SamplingHzEditFieldLabel = uilabel(app.AnalysisPanel);
            app.SamplingHzEditFieldLabel.Text = 'Sampling (Hz)';
            app.SamplingHzEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.SamplingHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingHzEditFieldValueChanged, true);
            app.SamplingHzEditField.Value = 100;
            app.ProtocolOrderTextAreaLabel = uilabel(app.AnalysisPanel);
            app.ProtocolOrderTextAreaLabel.Text = 'Protocol Order';
            app.ProtocolOrderTextArea = uitextarea(app.AnalysisPanel);
            app.ProtocolOrderTextArea.ValueChangedFcn = createCallbackFcn(app, @ProtocolOrderTextAreaValueChanged, true);
            app.ProtocolOrderTextArea.Value = {'Posture,Bimanual,Dystonic,Action'};
            app.RunButton = uibutton(app.AnalysisPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EpochSizesEditFieldLabel = uilabel(app.AnalysisPanel);
            app.EpochSizesEditFieldLabel.Text = 'Epoch Size (s)';
            app.EpochSizesEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.EpochSizesEditField.ValueChangedFcn = createCallbackFcn(app, @EpochSizesEditFieldValueChanged, true);
            app.EpochSizesEditField.Value = 10;
            app.TextArea = uitextarea(app.AnalysisPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'... no data'};
            app.NameLabel = uilabel(app.AnalysisPanel);
            app.NameLabel.Text = '...';
            app.OpLabel = uilabel(app.AnalysisPanel);
            app.OpLabel.Text = '...';
            app.DateLabel = uilabel(app.AnalysisPanel);
            app.DateLabel.Text = 'Date:';
            app.SummaryButton = uibutton(app.AnalysisPanel, 'push');
            app.SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SummaryButtonPushed, true);
            app.SummaryButton.Enable = 'off';
            app.SummaryButton.Text = 'Summary';
            app.SideLabel = uilabel(app.AnalysisPanel);
            app.SideLabel.Text = '...';
            app.TabGroup = uitabgroup(app.TremorAppUIFigure);
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.LowerLimitHzLabel = uilabel(app.FilteringTab);
            app.LowerLimitHzLabel.Text = 'Lower Limit (Hz)';
            app.LowerLimitHzEditField = uieditfield(app.FilteringTab, 'numeric');
            app.LowerLimitHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitHzEditFieldValueChanged, true);
            app.LowerLimitHzEditField.Value = 1;
            app.UpperLimitHzEditFieldLabel = uilabel(app.FilteringTab);
            app.UpperLimitHzEditFieldLabel.Text = 'Upper Limit (Hz)';
            app.UpperLimitHzEditField = uieditfield(app.FilteringTab, 'numeric');
            app.UpperLimitHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitHzEditFieldValueChanged, true);
            app.UpperLimitHzEditField.Value = 20;
            app.FilterOrderEditFieldLabel = uilabel(app.FilteringTab);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilteringTab, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 250;
            app.DisplayButtonGroup = uibuttongroup(app.FilteringTab);
            app.DisplayButtonGroup.Title = 'Display';
            app.ShowFilterDesignButton = uibutton(app.DisplayButtonGroup, 'push');
            app.ShowFilterDesignButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFilterDesignButtonPushed, true);
            app.ShowFilterDesignButton.Text = 'Show Filter Design';
            app.ShowFilteringButton = uibutton(app.DisplayButtonGroup, 'push');
            app.ShowFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFilteringButtonPushed, true);
            app.ShowFilteringButton.Text = 'Show Filtering';
            app.PCAnalysisTab = uitab(app.TabGroup);
            app.PCAnalysisTab.Title = 'PC Analysis';
            app.PCAButtonGroup = uibuttongroup(app.PCAnalysisTab);
            app.PCAButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PCAButtonGroupSelectionChanged, true);
            app.PCAButtonGroup.Title = 'PCA';
            app.PostfilteringButton = uiradiobutton(app.PCAButtonGroup);
            app.PostfilteringButton.Text = 'Post filtering';
            app.SelectedProtocolPeriodButton = uiradiobutton(app.PCAButtonGroup);
            app.SelectedProtocolPeriodButton.Text = 'Selected Protocol Period';
            app.SelectedProtocolPeriodButton.Value = true;
            app.DisplayButtonGroup_2 = uibuttongroup(app.PCAnalysisTab);
            app.DisplayButtonGroup_2.Title = 'Display';
            app.DisplayPCACloudButton = uibutton(app.DisplayButtonGroup_2, 'push');
            app.DisplayPCACloudButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPCACloudButtonPushed, true);
            app.DisplayPCACloudButton.Text = 'Display PCA Cloud';
            app.EpochsTab = uitab(app.TabGroup);
            app.EpochsTab.Title = 'Epochs';
            app.EpochSelectionButtonGroup = uibuttongroup(app.EpochsTab);
            app.EpochSelectionButtonGroup.Title = 'Epoch Selection';
            app.AutoButton = uitogglebutton(app.EpochSelectionButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.ManualButton = uitogglebutton(app.EpochSelectionButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.DisplayButtonGroup_3 = uibuttongroup(app.EpochsTab);
            app.DisplayButtonGroup_3.Title = 'Display';
            app.ShowSelectedEpochsButton = uibutton(app.DisplayButtonGroup_3, 'push');
            app.ShowSelectedEpochsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedEpochsButtonPushed, true);
            app.ShowSelectedEpochsButton.Text = 'Show Selected Epochs';
            app.PC1CheckBox = uicheckbox(app.DisplayButtonGroup_3);
            app.PC1CheckBox.Text = 'PC 1';
            app.PC1CheckBox.Value = true;
            app.PC2CheckBox = uicheckbox(app.DisplayButtonGroup_3);
            app.PC2CheckBox.Text = 'PC 2';
            app.PC2CheckBox.Value = true;
            app.PC3CheckBox = uicheckbox(app.DisplayButtonGroup_3);
            app.PC3CheckBox.Text = 'PC 3';
            app.PC3CheckBox.Value = true;
            app.ProtocolTypeButtonGroup = uibuttongroup(app.EpochsTab);
            app.ProtocolTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProtocolTypeButtonGroupSelectionChanged, true);
            app.ProtocolTypeButtonGroup.Title = 'Protocol Type';
            app.SinglePeakStartButton = uiradiobutton(app.ProtocolTypeButtonGroup);
            app.SinglePeakStartButton.Text = 'Single Peak Start';
            app.SinglePeakStartButton.Value = true;
            app.DoublePeakStartButton = uiradiobutton(app.ProtocolTypeButtonGroup);
            app.DoublePeakStartButton.Text = 'Double Peak Start';
            app.MovementTab = uitab(app.TabGroup);
            app.MovementTab.Title = 'Movement';
            app.DisplayButtonGroup_5 = uibuttongroup(app.MovementTab);
            app.DisplayButtonGroup_5.Title = 'Display';
            app.ShowMovementButton = uibutton(app.DisplayButtonGroup_5, 'push');
            app.ShowMovementButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMovementButtonPushed, true);
            app.ShowMovementButton.Text = 'Show Movement';
            app.FilterMovementCheckBox = uicheckbox(app.MovementTab);
            app.FilterMovementCheckBox.Text = 'Filter Movement';
            app.FilterMovementCheckBox.Value = true;
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.PCButtonGroup = uibuttongroup(app.PowerTab);
            app.PCButtonGroup.Title = 'PC';
            app.Button = uiradiobutton(app.PCButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.PCButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.PCButtonGroup);
            app.Button_3.Text = '3';
            app.TracesButtonGroup = uibuttongroup(app.PowerTab);
            app.TracesButtonGroup.Title = 'Traces';
            app.AverageOnlyButton = uitogglebutton(app.TracesButtonGroup);
            app.AverageOnlyButton.Text = 'Average Only';
            app.AverageOnlyButton.Value = true;
            app.AllButton = uitogglebutton(app.TracesButtonGroup);
            app.AllButton.Text = 'All';
            app.DisplayButtonGroup_4 = uibuttongroup(app.PowerTab);
            app.DisplayButtonGroup_4.Title = 'Display';
            app.ShowPowerButton = uibutton(app.DisplayButtonGroup_4, 'push');
            app.ShowPowerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPowerButtonPushed, true);
            app.ShowPowerButton.Text = 'Show Power';
            app.PowerRangeEditFieldLabel = uilabel(app.PowerTab);
            app.PowerRangeEditFieldLabel.Text = '% Power Range';
            app.PowerRangeEditField = uieditfield(app.PowerTab, 'numeric');
            app.PowerRangeEditField.ValueChangedFcn = createCallbackFcn(app, @PowerRangeEditFieldValueChanged, true);
            app.PowerRangeEditField.Value = 50;
            app.SaveDataButton = uibutton(app.TremorAppUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TremorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TremorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TremorAppUIFigure)
        end
    end
end